classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Panel_3               matlab.ui.container.Panel
        MapBuildingLamp       matlab.ui.control.Lamp
        AutoexploremodeLabel  matlab.ui.control.Label
        AutoexploreSwitch     matlab.ui.control.Switch
        ConnectionLamp        matlab.ui.control.Lamp
        ConnectionLampLabel   matlab.ui.control.Label
        Panel                 matlab.ui.container.Panel
        BtnFullStop           matlab.ui.control.StateButton
        BtnLeft               matlab.ui.control.StateButton
        BtnBackward           matlab.ui.control.StateButton
        BtnRight              matlab.ui.control.StateButton
        BtnForward            matlab.ui.control.StateButton
        BtnCW                 matlab.ui.control.StateButton
        BtnCCW                matlab.ui.control.StateButton
        BtnConnect            matlab.ui.control.Button
        cameraAxes            matlab.ui.control.UIAxes
        mapOccupancyAxes      matlab.ui.control.UIAxes
        mapTrajectoryAxes     matlab.ui.control.UIAxes
    end

    properties(Constant)

    end
    properties (Access = public)
        statusOffColor = [0.5, 0.5, 0.5];
        statusOnColor = 'green';
        
        movementController % Movement controller object
        connection  % Instance of ConnectionManager
        
        slamManager % Instance of the SlamManager class
        wanderer % Instance of the Wanderer class
        maps % Instance of the Wanderer class

        
        worldMap % occupancy map
        
        robotStartPosition % this is used to center the map in the middle
        
        currentPositionOfTheRobot


        timerUpdateData
        timerUpdateMap
        timerMoveTheRobot
        timerRecalculatePath
        timerToUpdateSlam
        timerToUpdateCamera

        
        plannedPath
        optimisedPath


        % Set goal radius
        goalRadius = 0.1;
        targetReached = false;

        frontObstacleDistance
        
         
        camHandle
        hImage 


    end
    
    properties (Access = public)
       
    end
    
    methods (Access = private)
        function stopAllProcesses(app)
            % Get all timer objects
            timers = timerfindall;
            
            % Stop and delete each timer
            if ~isempty(timers)
                stop(timers);
                disp('All timers have been stopped.');
            else
                disp('No active timers found.');
            end
            app.AutoexploreSwitch.Value = 'Off';
            
        end
        function startAllProcesses(app)

            if isempty(app.worldMap)
               firstRun = app.slamManager.initiateOccupancyMap();
               app.worldMap =  firstRun.worldMap;
               app.robotStartPosition = firstRun.robotStartPosition;
            end
             if strcmp(app.timerUpdateData.Running, 'off')
                start(app.timerUpdateData);
             end
             if strcmp(app.timerUpdateMap.Running, 'off')
                start(app.timerUpdateMap);
             end
             % if strcmp(app.timerRecalculatePath.Running, 'off')
             %    start(app.timerRecalculatePath);
             % end
             if strcmp(app.timerToUpdateCamera.Running, 'off')
                start(app.timerToUpdateCamera);
             end
        end

        function updateConnectionStatus(app)
            if app.connection.clientID == -1
                app.BtnConnect.Text = 'Connect';
                app.BtnConnect.Icon = 'icons/Connect.png';
                app.ConnectionLamp.Color = app.statusOffColor;
                app.updateMovementButtonsState("off");
            else
                app.BtnConnect.Text = 'Disconnect';
                app.BtnConnect.Icon = 'icons/Disconnect.png';
                app.ConnectionLamp.Color = app.statusOnColor;
                app.updateMovementButtonsState("on");
            end
        end

        function updateMapBuildingIndicator(app)
            if strcmp(app.timerUpdateMap.Running, 'off')
                app.MapBuildingLamp.Color = app.statusOffColor;
            else
                app.MapBuildingLamp.Color = app.statusOnColor;
            end
        end
 
        function updateMovementButtonsState(app,state)
            movementButtons = {app.BtnForward, app.BtnBackward, app.BtnLeft, app.BtnRight, app.BtnCW, app.BtnCCW, app.BtnFullStop};
            % Loop through all movement buttons and set their status
            for i = 1:length(movementButtons)
                movementButtons{i}.Enable = state;
            end
            app.AutoexploreSwitch.Enable = state;
            app.AutoexploremodeLabel.Enable = state;
            if strcmp(state,"off")
                app.AutoexploreSwitch.Value = 'Off';
            end
        end

        function initializeControlButtons(app)
            app.updateConnectionStatus();
            
            app.MapBuildingLamp.Color = app.statusOffColor;
            
            %Assign unique identifiers to the movement buttons so we can
            %have a single generic method to move the robot

            app.BtnForward.Tag = 'FORWARD';
            app.BtnBackward.Tag = 'BACKWARD';
            app.BtnLeft.Tag = 'LEFT';
            app.BtnRight.Tag = 'RIGHT';
            app.BtnCW.Tag = 'CLOCKWISE';
            app.BtnCCW.Tag = 'COUNTER_CLOCKWISE';
            app.BtnFullStop.Tag = 'STOP';

        end

        function updateData(app)

            % Update the SLAM object and robot pose from LaserScanner
            slamHandle = app.slamManager.UpdateSLAM();

            app.currentPositionOfTheRobot = slamHandle.currentPosition;
            app.frontObstacleDistance = slamHandle.frontObstacleDistance;
            app.worldMap = slamHandle.worldMap;
        end

        % Update the camera feed (in a loop or timer)
        function refreshCameraFeed(app)
            % Get the current camera frame from CoppeliaSim
            [res, ~, image] = app.connection.sim.simxGetVisionSensorImage2(app.connection.clientID, app.camHandle, 0, app.connection.sim.simx_opmode_buffer);
            
            if res == app.connection.sim.simx_return_ok
                set(app.hImage, 'CData', image);  % Update the image in UIAxes
                drawnow;  % Refresh display
            end
        end

        function initializeComponents(app)
 
            % Retrieve wheel handles from CoppeliaSim 
            [~,rightMotor] = app.connection.getHandle('/Robot/rightMotor', app.UIFigure);
            [~,leftMotor] = app.connection.getHandle('/Robot/leftMotor', app.UIFigure);
            % Store the handles in an array to pass to movementController
            wheelHandles = [rightMotor, leftMotor];
            % Initialize the movementController object
            app.movementController = MovementController(app.connection,wheelHandles);
            
            
            % Get the vision sensor handle
            [~, app.camHandle] = app.connection.getHandle('/Camera', app.UIFigure);
            % Start image streaming
            [~, resolution, ~] = app.connection.sim.simxGetVisionSensorImage2(app.connection.clientID, app.camHandle, 0, app.connection.sim.simx_opmode_streaming);
            % Initialize image display on UIAxes
            app.hImage = imshow(zeros(resolution(2), resolution(1), 3), 'Parent', app.cameraAxes);
            
         
            % Initialize the SLAM handler object
            app.slamManager = SLAMManager(app.connection,20,10);
            
            % Initialize the Wanderer  object
            app.wanderer = Wanderer(app.movementController);

            % Initialize the Maps  object
            app.maps = Maps(app.mapOccupancyAxes, app.mapTrajectoryAxes);
                       
        end

        function updateMaps(app)
            app.maps.updateOccupancyMap(app.worldMap, app.currentPositionOfTheRobot,app.plannedPath, app.robotStartPosition);
            app.maps.updateTrajectoryMap(app.slamManager.slamObj,app.plannedPath);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFunction(app)
            % Initialize ConnectionManager
            app.connection = ConnectionManager();
            app.initializeControlButtons();

            app.timerUpdateData = timer('ExecutionMode', 'fixedRate', ...
                                   'Period', 0.3, ... % Update every x second; 
                                   'TimerFcn', @(~,~) app.slamManager.updateSLAM());

            app.timerUpdateMap = timer('ExecutionMode', 'fixedRate', ...
                                   'Period', 1, ... % Update every x second; 
                                   'TimerFcn', @(~,~) app.updateMaps());

            app.timerMoveTheRobot = timer('ExecutionMode', 'fixedRate', ...
                                   'Period', 2.5, ... % Update every x second; 
                                   'TimerFcn', @(~,~) app.executeRobotMovement());

            
            app.timerRecalculatePath = timer('ExecutionMode', 'fixedRate', ...
                                   'Period', 3, ... % Update every x second; 
                                   'TimerFcn', @(~,~) app.planRobotTrajectory());

            app.timerToUpdateCamera = timer('ExecutionMode', 'fixedRate', ...
                        'Period',0.2, ...  % Adjust for desired frame rate
                        'TimerFcn', @(~,~) app.refreshCameraFeed());


        end

        % Button pushed function: BtnConnect
        function ConnectButtonPushed(app, event)
            if ~app.connection.isSimulationRunning
                success = app.connection.connect();
                if success
                    app.initializeComponents();
                else
                    %failed to connect to Coppelia
                    uialert(app.UIFigure, 'Connection failed.Something went wrong, please check the logs', 'Status');
                end
            else
                app.connection.disconnect();
                app.stopAllProcesses();
                
            end
            app.updateConnectionStatus();
        end

        % Value changed function: BtnBackward, BtnCCW, BtnCW, BtnForward, 
        % ...and 3 other components
        function MovementButtonPushed(app, event)
            value = event.Source.Value;
            
            movementType = event.Source.Tag;  % Get the movement type from the button's tag
            
            % Create a list of all movement buttons
            movementButtons = {app.BtnForward, app.BtnBackward, app.BtnLeft, app.BtnRight, app.BtnCW, app.BtnCCW};

            % Take out from the list the pressed button
            pressedButton = event.Source;
            remainingButtons = movementButtons(~cellfun(@(btn) isequal(btn, pressedButton), movementButtons));
            
            % Loop through all the other movement buttons and set their value to 0
                for i = 1:length(remainingButtons)
                    remainingButtons{i}.Value = 0;
                end

            % if the Full stop button is clicked
            if strcmp(movementType, 'STOP')
                app.stopAllProcesses();
                app.movementController.stop();
                app.BtnFullStop.Value = 0;
            else
                if strcmp(app.timerUpdateMap.Running, 'off')
                    start(app.timerUpdateMap);
                end
                if strcmp(app.timerToUpdateCamera.Running, 'off')
                    start(app.timerToUpdateCamera);
                end
                % For other movement buttons, move the robot if pressed
                if value == 1  % Button is pressed
                    app.movementController.move(app.movementController.WheelMovements.(movementType));
                else
                    app.movementController.stop();
                end
            end
            app.updateMapBuildingIndicator();
        end

        % Value changed function: AutoexploreSwitch
        function AutoexploreSwitchValueChanged(app, event)
            if strcmp(app.timerRecalculatePath.Running, 'off')
                app.startAllProcesses();
                disp('Exploration started.');
            else
                % Stop exploration
                disp('Exploration stopped.');
                app.stopAllProcesses();
            end
            app.updateMapBuildingIndicator();
        end

        % Close request function: UIFigure
        function CloseRequested(app, event)
            %check if it's running and stop the simulation 
            app.stopAllProcesses();
            if app.connection.isSimulationRunning
                app.connection.disconnect();
            end
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @CloseRequested, true);
            app.mapTrajectoryAxes = uiaxes(app.UIFigure);
            title(app.mapTrajectoryAxes, 'Trajectory map')
            app.mapTrajectoryAxes.XTick = [];
            app.mapTrajectoryAxes.YTick = [];
            app.mapOccupancyAxes = uiaxes(app.UIFigure);
            title(app.mapOccupancyAxes, 'Occupancy map')
            app.mapOccupancyAxes.XTick = [];
            app.mapOccupancyAxes.YTick = [];
            app.cameraAxes = uiaxes(app.UIFigure);
            title(app.cameraAxes, 'Camera')
            app.cameraAxes.XTick = [];
            app.cameraAxes.YTick = [];
            app.BtnConnect = uibutton(app.UIFigure, 'push');
            app.BtnConnect.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.BtnConnect.Icon = fullfile(pathToMLAPP, 'icons', 'Connect.png');
            app.BtnConnect.Text = '';
            app.Panel = uipanel(app.UIFigure);
            app.BtnCCW = uibutton(app.Panel, 'state');
            app.BtnCCW.ValueChangedFcn = createCallbackFcn(app, @MovementButtonPushed, true);
            app.BtnCCW.Enable = 'off';
            app.BtnCCW.Icon = fullfile(pathToMLAPP, 'icons', 'CCW.png');
            app.BtnCCW.Text = '';
            app.BtnCW = uibutton(app.Panel, 'state');
            app.BtnCW.ValueChangedFcn = createCallbackFcn(app, @MovementButtonPushed, true);
            app.BtnCW.Enable = 'off';
            app.BtnCW.Icon = fullfile(pathToMLAPP, 'icons', 'CW.png');
            app.BtnCW.Text = '';
            app.BtnForward = uibutton(app.Panel, 'state');
            app.BtnForward.ValueChangedFcn = createCallbackFcn(app, @MovementButtonPushed, true);
            app.BtnForward.Enable = 'off';
            app.BtnForward.Icon = fullfile(pathToMLAPP, 'icons', 'ArrowUp.png');
            app.BtnForward.Text = '';
            app.BtnRight = uibutton(app.Panel, 'state');
            app.BtnRight.ValueChangedFcn = createCallbackFcn(app, @MovementButtonPushed, true);
            app.BtnRight.Enable = 'off';
            app.BtnRight.Icon = fullfile(pathToMLAPP, 'icons', 'ArrowRight.png');
            app.BtnRight.Text = '';
            app.BtnBackward = uibutton(app.Panel, 'state');
            app.BtnBackward.ValueChangedFcn = createCallbackFcn(app, @MovementButtonPushed, true);
            app.BtnBackward.Enable = 'off';
            app.BtnBackward.Icon = fullfile(pathToMLAPP, 'icons', 'ArrowDown.png');
            app.BtnBackward.Text = '';
            app.BtnLeft = uibutton(app.Panel, 'state');
            app.BtnLeft.ValueChangedFcn = createCallbackFcn(app, @MovementButtonPushed, true);
            app.BtnLeft.Enable = 'off';
            app.BtnLeft.Icon = fullfile(pathToMLAPP, 'icons', 'ArrowLeft.png');
            app.BtnLeft.Text = '';
            app.BtnFullStop = uibutton(app.Panel, 'state');
            app.BtnFullStop.ValueChangedFcn = createCallbackFcn(app, @MovementButtonPushed, true);
            app.BtnFullStop.Enable = 'off';
            app.BtnFullStop.Icon = fullfile(pathToMLAPP, 'icons', 'Stop.png');
            app.BtnFullStop.Text = '';
            app.ConnectionLampLabel = uilabel(app.UIFigure);
            app.ConnectionLampLabel.Text = 'Connection';
            app.ConnectionLamp = uilamp(app.UIFigure);
            app.Panel_3 = uipanel(app.UIFigure);
            app.AutoexploreSwitch = uiswitch(app.Panel_3, 'slider');
            app.AutoexploreSwitch.ValueChangedFcn = createCallbackFcn(app, @AutoexploreSwitchValueChanged, true);
            app.AutoexploreSwitch.Enable = 'off';
            app.AutoexploremodeLabel = uilabel(app.Panel_3);
            app.AutoexploremodeLabel.Enable = 'off';
            app.AutoexploremodeLabel.Text = 'Auto explore mode';
            app.MapBuildingLamp = uilamp(app.Panel_3);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @StartupFunction)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end